classdef cnn_Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SajotesterButton          matlab.ui.control.Button
        ArchLabel                 matlab.ui.control.Label
        IzaberiarhitekturuButton  matlab.ui.control.Button
        ArhitekturaLabel          matlab.ui.control.Label
        DobrodoliuCNNanalizuslikaPhotoLabaLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Architecture % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IzaberiarhitekturuButton
        function IzaberiarhitekturuButtonPushed(app, event)
            architectures = CNN.CNNArchitectures();
            architectures.ParentApp = app; % Pass the parent app reference
    
            % Wait for the user to select an architecture
            uiwait(architectures.CNNArchitecturesUIFigure);
            
            % Access the chosen architecture from the child app
            if isvalid(architectures) && ~isempty(architectures.choosenArch)
                app.Architecture = architectures.choosenArch; % Update parent app
                app.ArchLabel.Text = architectures.choosenArch;
            end

            delete(architectures);
        end

        % Button pushed function: SajotesterButton
        function SajotesterButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DobrodoliuCNNanalizuslikaPhotoLabaLabel = uilabel(app.UIFigure);
            app.DobrodoliuCNNanalizuslikaPhotoLabaLabel.Text = 'Dobro došli u CNN analizu slika PhotoLab-a';
            app.ArhitekturaLabel = uilabel(app.UIFigure);
            app.ArhitekturaLabel.Text = 'Arhitektura:';
            app.IzaberiarhitekturuButton = uibutton(app.UIFigure, 'push');
            app.IzaberiarhitekturuButton.ButtonPushedFcn = createCallbackFcn(app, @IzaberiarhitekturuButtonPushed, true);
            app.IzaberiarhitekturuButton.Text = 'Izaberi arhitekturu';
            app.ArchLabel = uilabel(app.UIFigure);
            app.ArchLabel.Text = '';
            app.SajotesterButton = uibutton(app.UIFigure, 'push');
            app.SajotesterButton.ButtonPushedFcn = createCallbackFcn(app, @SajotesterButtonPushed, true);
            app.SajotesterButton.Text = 'Sajo tester';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cnn_Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end