classdef ObjectDetectionApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PokrenidetekcijuButton      matlab.ui.control.Button
        UitajslikuButton            matlab.ui.control.Button
        OdaberitealgoritamDropDown  matlab.ui.control.DropDown
        OdaberitealgoritamDropDownLabel  matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OdaberitealgoritamDropDown
        function AlgorithmDropDownValueChanged(app, event)
           % SIGURNA PROVERA: Inicijalizuj UserData ako ne postoji
    if isempty(app.UIFigure.UserData)
        app.UIFigure.UserData = struct();
        app.UIFigure.UserData.detector = [];
        app.UIFigure.UserData.image = [];
    end
    
    choice = app.OdaberitealgoritamDropDown.Value;
    
    try
        switch choice
            case 'Yolo'
                % Pokušaj različite YOLO implementacije
                try
                    % Pokušaj sa YOLOv4
                    app.UIFigure.UserData.detector = yolov4ObjectDetector('csp-darknet53-coco');
                catch
                    try
                        % Pokušaj sa osnovnim YOLOv4
                        app.UIFigure.UserData.detector = yolov4ObjectDetector();
                    catch
                        try
                            % Pokušaj sa YOLOv3
                            app.UIFigure.UserData.detector = yolov3ObjectDetector('darknet53-coco');
                        catch
                            try
                                % Pokušaj sa YOLOv2
                                app.UIFigure.UserData.detector = yolov2ObjectDetector('darknet19-coco');
                            catch
                                error('Nijedan YOLO model nije dostupan');
                            end
                        end
                    end
                end
                
            case 'Faster R-CNN'
                % Pokušaj različite Faster R-CNN implementacije
                try
                    % Pokušaj sa ResNet-50
                    app.UIFigure.UserData.detector = fasterRCNNObjectDetector('resnet50-coco');
                catch
                    try
                        % Pokušaj sa ResNet-101
                        app.UIFigure.UserData.detector = fasterRCNNObjectDetector('resnet101-coco');
                    catch
                        try
                            % Pokušaj učitavanje iz fajla
                            if exist('fasterrcnnResNet50EndToEnd.mat', 'file')
                                data = load('fasterrcnnResNet50EndToEnd.mat','detector');
                                app.UIFigure.UserData.detector = data.detector;
                            else
                                error('Faster R-CNN model nije dostupan');
                            end
                        catch
                            error('Faster R-CNN model nije dostupan');
                        end
                    end
                end
        end
        
        uialert(app.UIFigure, ['Algoritam ' choice ' je uspešno učitan.'], 'Uspeh');
        
    catch ME
        errorMsg = sprintf('Greška pri učitavanju algoritma %s:\n%s\n\nMoguće rešenje:\n1. Proverite da li je model preuzet\n2. Proverite internet konekciju\n3. Pokušajte ponovo', choice, ME.message);
        uialert(app.UIFigure, errorMsg, 'Greška');
    end
        end

        % Button pushed function: UitajslikuButton
        function LoadImageButtonPushed(app, event)
           % SIGURNA PROVERA: Inicijalizuj UserData ako ne postoji
           if isempty(app.UIFigure.UserData)
               app.UIFigure.UserData = struct();
               app.UIFigure.UserData.detector = [];
               app.UIFigure.UserData.image = [];
           end
           
           try
                [f, p] = uigetfile({'*.jpg;*.png;*.jpeg;*.bmp;*.tiff', 'Slike (*.jpg,*.png,*.jpeg,*.bmp,*.tiff)'}, 'Izaberi sliku');
                if isequal(f,0)
                    return;  % korisnik je otkazao
                end

                % Učitaj sliku u svojstvo app.image
                app.UIFigure.UserData.image = imread(fullfile(p,f));

                % Prikaži sliku u UIAxes
                imshow(app.UIFigure.UserData.image, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Učitana slika');

                % Ukloni prethodne anotacije
                hold(app.UIAxes,'off');
                
                uialert(app.UIFigure, 'Slika je uspešno učitana.', 'Uspeh');
           catch ME
                   uialert(app.UIFigure, ['Greška pri učitavanju slike: ' ME.message], 'Greška');
           
            end
        end

        % Button pushed function: PokrenidetekcijuButton
        function RunDetectionButtonPushed(app, event)
           % SIGURNA PROVERA: Inicijalizuj UserData ako ne postoji
            if isempty(app.UIFigure.UserData)
                app.UIFigure.UserData = struct();
                app.UIFigure.UserData.detector = [];
                app.UIFigure.UserData.image = [];
            end
            
            % Proveri da li polja postoje
            if ~isfield(app.UIFigure.UserData, 'detector')
                app.UIFigure.UserData.detector = [];
            end
            if ~isfield(app.UIFigure.UserData, 'image')
                app.UIFigure.UserData.image = [];
            end
            
            % ISPRAVKA: Proveri da li UserData polja postoje
            if isempty(app.UIFigure.UserData.detector)
                uialert(app.UIFigure,'Prvo izaberite algoritam.','Upozorenje');
                return;
            end
            
            if isempty(app.UIFigure.UserData.image)
                uialert(app.UIFigure,'Prvo učitajte sliku.','Upozorenje');
                return;
            end

            try
                % Pokreni detekciju
                [bboxes, scores, labels] = detect(app.UIFigure.UserData.detector, app.UIFigure.UserData.image);
                
                if isempty(bboxes)
                    uialert(app.UIFigure, 'Nisu pronađeni objekti na slici.', 'Info');
                    imshow(app.UIFigure.UserData.image, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Nema detektovanih objekata');
                    return;
                end
                
                % Kreiraj labele sa skorovima
                if isnumeric(labels)
                    labelStrings = cellstr(string(labels));
                else
                    labelStrings = cellstr(labels);
                end
                
                % Dodaj skorove u labele
                for i = 1:length(labelStrings)
                    labelStrings{i} = sprintf('%s (%.2f)', labelStrings{i}, scores(i));
                end
                
                % Insertuj anotacije
                result = insertObjectAnnotation(app.UIFigure.UserData.image, 'rectangle', bboxes, labelStrings, ...
                    'Color', 'red', 'TextColor', 'black', 'FontSize', 14);
                
                % Prikaži rezultat
                imshow(result, 'Parent', app.UIAxes);
                title(app.UIAxes, sprintf('Detektovano %d objekata', size(bboxes,1)));
                
                % Prikaz informacija o detekciji
                infoMsg = sprintf('Detektovano %d objekata:\n', size(bboxes,1));
                for i = 1:min(5, length(labelStrings)) % Prikaži prvih 5
                    infoMsg = [infoMsg sprintf('- %s\n', labelStrings{i})];
                end
                if length(labelStrings) > 5
                    infoMsg = [infoMsg '...'];
                end
                
                uialert(app.UIFigure, infoMsg, 'Rezultati detekcije');
                
            catch ME
                uialert(app.UIFigure, ['Greška pri detekciji: ' ME.message], 'Greška');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Slika')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OdaberitealgoritamDropDownLabel = uilabel(app.UIFigure);
            app.OdaberitealgoritamDropDownLabel.Text = 'Odaberite algoritam';
            app.OdaberitealgoritamDropDown = uidropdown(app.UIFigure);
            app.OdaberitealgoritamDropDown.Items = {'Yolo', 'Faster R-CNN'};
            app.OdaberitealgoritamDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.OdaberitealgoritamDropDown.Value = 'Yolo';
            app.UitajslikuButton = uibutton(app.UIFigure, 'push');
            app.UitajslikuButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.UitajslikuButton.Text = 'Učitaj sliku';
            app.PokrenidetekcijuButton = uibutton(app.UIFigure, 'push');
            app.PokrenidetekcijuButton.ButtonPushedFcn = createCallbackFcn(app, @RunDetectionButtonPushed, true);
            app.PokrenidetekcijuButton.Text = 'Pokreni detekciju';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ObjectDetectionApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end